classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotArmGridUIFigure  matlab.ui.Figure
        ConfirmButton         matlab.ui.control.Button
        PrintPanel            matlab.ui.container.Panel
        GridLayout_2          matlab.ui.container.GridLayout
        Button_252            matlab.ui.control.StateButton
        Button_251            matlab.ui.control.StateButton
        Button_250            matlab.ui.control.StateButton
        Button_249            matlab.ui.control.StateButton
        Button_248            matlab.ui.control.StateButton
        Button_247            matlab.ui.control.StateButton
        Button_246            matlab.ui.control.StateButton
        Button_245            matlab.ui.control.StateButton
        Button_244            matlab.ui.control.StateButton
        Button_243            matlab.ui.control.StateButton
        Button_242            matlab.ui.control.StateButton
        Button_241            matlab.ui.control.StateButton
        Button_240            matlab.ui.control.StateButton
        Button_239            matlab.ui.control.StateButton
        Button_238            matlab.ui.control.StateButton
        Button_237            matlab.ui.control.StateButton
        Button_236            matlab.ui.control.StateButton
        Button_235            matlab.ui.control.StateButton
        Button_234            matlab.ui.control.StateButton
        Button_233            matlab.ui.control.StateButton
        Button_232            matlab.ui.control.StateButton
        Button_231            matlab.ui.control.StateButton
        Button_230            matlab.ui.control.StateButton
        Button_229            matlab.ui.control.StateButton
        Button_228            matlab.ui.control.StateButton
        Button_227            matlab.ui.control.StateButton
        Button_226            matlab.ui.control.StateButton
        Button_225            matlab.ui.control.StateButton
        Button_224            matlab.ui.control.StateButton
        Button_223            matlab.ui.control.StateButton
        Button_222            matlab.ui.control.StateButton
        Button_221            matlab.ui.control.StateButton
        Button_220            matlab.ui.control.StateButton
        Button_219            matlab.ui.control.StateButton
        Button_218            matlab.ui.control.StateButton
        Button_217            matlab.ui.control.StateButton
        Button_216            matlab.ui.control.StateButton
        Button_215            matlab.ui.control.StateButton
        Button_214            matlab.ui.control.StateButton
        Button_213            matlab.ui.control.StateButton
        Button_212            matlab.ui.control.StateButton
        Button_211            matlab.ui.control.StateButton
        Button_210            matlab.ui.control.StateButton
        Button_209            matlab.ui.control.StateButton
        Button_208            matlab.ui.control.StateButton
        Button_207            matlab.ui.control.StateButton
        Button_206            matlab.ui.control.StateButton
        Button_205            matlab.ui.control.StateButton
        Button_204            matlab.ui.control.StateButton
        Button_203            matlab.ui.control.StateButton
        Button_202            matlab.ui.control.StateButton
        Button_201            matlab.ui.control.StateButton
        Button_200            matlab.ui.control.StateButton
        Button_199            matlab.ui.control.StateButton
        Button_198            matlab.ui.control.StateButton
        Button_197            matlab.ui.control.StateButton
        Button_196            matlab.ui.control.StateButton
        Button_195            matlab.ui.control.StateButton
        Button_194            matlab.ui.control.StateButton
        Button_193            matlab.ui.control.StateButton
        Button_192            matlab.ui.control.StateButton
        Button_191            matlab.ui.control.StateButton
        Button_190            matlab.ui.control.StateButton
        Button_189            matlab.ui.control.StateButton
        Button_188            matlab.ui.control.StateButton
        Button_187            matlab.ui.control.StateButton
        Button_186            matlab.ui.control.StateButton
        Button_185            matlab.ui.control.StateButton
        Button_184            matlab.ui.control.StateButton
        Button_183            matlab.ui.control.StateButton
        Button_182            matlab.ui.control.StateButton
        Button_181            matlab.ui.control.StateButton
        Button_180            matlab.ui.control.StateButton
        Button_179            matlab.ui.control.StateButton
        Button_178            matlab.ui.control.StateButton
        Button_177            matlab.ui.control.StateButton
        Button_176            matlab.ui.control.StateButton
        Button_175            matlab.ui.control.StateButton
        Button_174            matlab.ui.control.StateButton
        Button_173            matlab.ui.control.StateButton
        Button_172            matlab.ui.control.StateButton
        Button_171            matlab.ui.control.StateButton
        Button_170            matlab.ui.control.StateButton
        Button_169            matlab.ui.control.StateButton
        Button_168            matlab.ui.control.StateButton
        Button_167            matlab.ui.control.StateButton
        Button_166            matlab.ui.control.StateButton
        Button_165            matlab.ui.control.StateButton
        Button_164            matlab.ui.control.StateButton
        Button_163            matlab.ui.control.StateButton
        Button_162            matlab.ui.control.StateButton
        Button_161            matlab.ui.control.StateButton
        Button_160            matlab.ui.control.StateButton
        Button_159            matlab.ui.control.StateButton
        Button_158            matlab.ui.control.StateButton
        Button_157            matlab.ui.control.StateButton
        Button_156            matlab.ui.control.StateButton
        Button_155            matlab.ui.control.StateButton
        Button_154            matlab.ui.control.StateButton
        Button_153            matlab.ui.control.StateButton
        Button_152            matlab.ui.control.StateButton
        Button_151            matlab.ui.control.StateButton
        Button_150            matlab.ui.control.StateButton
        Button_149            matlab.ui.control.StateButton
        Button_148            matlab.ui.control.StateButton
        Button_147            matlab.ui.control.StateButton
        Button_146            matlab.ui.control.StateButton
        Button_145            matlab.ui.control.StateButton
        Button_144            matlab.ui.control.StateButton
        Button_143            matlab.ui.control.StateButton
        Button_142            matlab.ui.control.StateButton
        Button_141            matlab.ui.control.StateButton
        Button_140            matlab.ui.control.StateButton
        Button_139            matlab.ui.control.StateButton
        Button_138            matlab.ui.control.StateButton
        Button_137            matlab.ui.control.StateButton
        Button_136            matlab.ui.control.StateButton
        Button_135            matlab.ui.control.StateButton
        Button_134            matlab.ui.control.StateButton
        Button_133            matlab.ui.control.StateButton
        Button_132            matlab.ui.control.StateButton
        Button_131            matlab.ui.control.StateButton
        Button_130            matlab.ui.control.StateButton
        Button_129            matlab.ui.control.StateButton
        Button_128            matlab.ui.control.StateButton
        Button_127            matlab.ui.control.StateButton
        SourcePanel           matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        Button_126            matlab.ui.control.StateButton
        Button_125            matlab.ui.control.StateButton
        Button_124            matlab.ui.control.StateButton
        Button_123            matlab.ui.control.StateButton
        Button_122            matlab.ui.control.StateButton
        Button_121            matlab.ui.control.StateButton
        Button_120            matlab.ui.control.StateButton
        Button_119            matlab.ui.control.StateButton
        Button_118            matlab.ui.control.StateButton
        Button_117            matlab.ui.control.StateButton
        Button_116            matlab.ui.control.StateButton
        Button_115            matlab.ui.control.StateButton
        Button_114            matlab.ui.control.StateButton
        Button_113            matlab.ui.control.StateButton
        Button_112            matlab.ui.control.StateButton
        Button_111            matlab.ui.control.StateButton
        Button_110            matlab.ui.control.StateButton
        Button_109            matlab.ui.control.StateButton
        Button_108            matlab.ui.control.StateButton
        Button_107            matlab.ui.control.StateButton
        Button_106            matlab.ui.control.StateButton
        Button_105            matlab.ui.control.StateButton
        Button_104            matlab.ui.control.StateButton
        Button_103            matlab.ui.control.StateButton
        Button_102            matlab.ui.control.StateButton
        Button_101            matlab.ui.control.StateButton
        Button_100            matlab.ui.control.StateButton
        Button_99             matlab.ui.control.StateButton
        Button_98             matlab.ui.control.StateButton
        Button_97             matlab.ui.control.StateButton
        Button_96             matlab.ui.control.StateButton
        Button_95             matlab.ui.control.StateButton
        Button_94             matlab.ui.control.StateButton
        Button_93             matlab.ui.control.StateButton
        Button_92             matlab.ui.control.StateButton
        Button_91             matlab.ui.control.StateButton
        Button_90             matlab.ui.control.StateButton
        Button_89             matlab.ui.control.StateButton
        Button_88             matlab.ui.control.StateButton
        Button_87             matlab.ui.control.StateButton
        Button_86             matlab.ui.control.StateButton
        Button_85             matlab.ui.control.StateButton
        Button_84             matlab.ui.control.StateButton
        Button_83             matlab.ui.control.StateButton
        Button_82             matlab.ui.control.StateButton
        Button_81             matlab.ui.control.StateButton
        Button_80             matlab.ui.control.StateButton
        Button_79             matlab.ui.control.StateButton
        Button_78             matlab.ui.control.StateButton
        Button_77             matlab.ui.control.StateButton
        Button_76             matlab.ui.control.StateButton
        Button_75             matlab.ui.control.StateButton
        Button_74             matlab.ui.control.StateButton
        Button_73             matlab.ui.control.StateButton
        Button_72             matlab.ui.control.StateButton
        Button_71             matlab.ui.control.StateButton
        Button_70             matlab.ui.control.StateButton
        Button_69             matlab.ui.control.StateButton
        Button_68             matlab.ui.control.StateButton
        Button_67             matlab.ui.control.StateButton
        Button_66             matlab.ui.control.StateButton
        Button_65             matlab.ui.control.StateButton
        Button_64             matlab.ui.control.StateButton
        Button_63             matlab.ui.control.StateButton
        Button_62             matlab.ui.control.StateButton
        Button_61             matlab.ui.control.StateButton
        Button_60             matlab.ui.control.StateButton
        Button_59             matlab.ui.control.StateButton
        Button_58             matlab.ui.control.StateButton
        Button_57             matlab.ui.control.StateButton
        Button_56             matlab.ui.control.StateButton
        Button_55             matlab.ui.control.StateButton
        Button_54             matlab.ui.control.StateButton
        Button_53             matlab.ui.control.StateButton
        Button_52             matlab.ui.control.StateButton
        Button_51             matlab.ui.control.StateButton
        Button_50             matlab.ui.control.StateButton
        Button_49             matlab.ui.control.StateButton
        Button_48             matlab.ui.control.StateButton
        Button_47             matlab.ui.control.StateButton
        Button_46             matlab.ui.control.StateButton
        Button_45             matlab.ui.control.StateButton
        Button_44             matlab.ui.control.StateButton
        Button_43             matlab.ui.control.StateButton
        Button_42             matlab.ui.control.StateButton
        Button_41             matlab.ui.control.StateButton
        Button_40             matlab.ui.control.StateButton
        Button_39             matlab.ui.control.StateButton
        Button_38             matlab.ui.control.StateButton
        Button_37             matlab.ui.control.StateButton
        Button_36             matlab.ui.control.StateButton
        Button_35             matlab.ui.control.StateButton
        Button_34             matlab.ui.control.StateButton
        Button_33             matlab.ui.control.StateButton
        Button_32             matlab.ui.control.StateButton
        Button_31             matlab.ui.control.StateButton
        Button_30             matlab.ui.control.StateButton
        Button_29             matlab.ui.control.StateButton
        Button_28             matlab.ui.control.StateButton
        Button_27             matlab.ui.control.StateButton
        Button_26             matlab.ui.control.StateButton
        Button_25             matlab.ui.control.StateButton
        Button_24             matlab.ui.control.StateButton
        Button_23             matlab.ui.control.StateButton
        Button_22             matlab.ui.control.StateButton
        Button_21             matlab.ui.control.StateButton
        Button_20             matlab.ui.control.StateButton
        Button_19             matlab.ui.control.StateButton
        Button_18             matlab.ui.control.StateButton
        Button_17             matlab.ui.control.StateButton
        Button_16             matlab.ui.control.StateButton
        Button_15             matlab.ui.control.StateButton
        Button_14             matlab.ui.control.StateButton
        Button_13             matlab.ui.control.StateButton
        Button_12             matlab.ui.control.StateButton
        Button_11             matlab.ui.control.StateButton
        Button_10             matlab.ui.control.StateButton
        Button_9              matlab.ui.control.StateButton
        Button_8              matlab.ui.control.StateButton
        Button_7              matlab.ui.control.StateButton
        Button_6              matlab.ui.control.StateButton
        Button_5              matlab.ui.control.StateButton
        Button_4              matlab.ui.control.StateButton
        Button_3              matlab.ui.control.StateButton
        Button_2              matlab.ui.control.StateButton
        Button_1              matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Get soruce matrix
            source = [zeros(14,9)];
            
            for i=1:126
                source(1+fix((i-1)/9), 1+mod(i-1,9)) = app.(sprintf('Button_%d',i)).Value;
            end
     
            assignin('base','source',source)


            % Get print matrix
            print = [zeros(14,9)];
            
            for i=126:252
                print(1+fix(((i-126)-1)/9), 1+mod((i-126)-1,9)) = app.(sprintf('Button_%d',i)).Value;
            end
            
            assignin('base','print',print)
            
            % Close window
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotArmGridUIFigure = uifigure('Visible', 'off');
            app.RobotArmGridUIFigure.Name = 'Robot Arm Grid';
            app.SourcePanel = uipanel(app.RobotArmGridUIFigure);
            app.SourcePanel.Title = 'Source';
            app.GridLayout = uigridlayout(app.SourcePanel);
            app.Button_1 = uibutton(app.GridLayout, 'state');
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'state');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'state');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'state');
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'state');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'state');
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'state');
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.Enable = 'off';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'state');
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'state');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.GridLayout, 'state');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.GridLayout, 'state');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.GridLayout, 'state');
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.GridLayout, 'state');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'state');
            app.Button_15.Enable = 'off';
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.GridLayout, 'state');
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout, 'state');
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.GridLayout, 'state');
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.GridLayout, 'state');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'state');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.GridLayout, 'state');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.GridLayout, 'state');
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.GridLayout, 'state');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.GridLayout, 'state');
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.GridLayout, 'state');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.GridLayout, 'state');
            app.Button_26.Enable = 'off';
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.GridLayout, 'state');
            app.Button_27.Enable = 'off';
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.GridLayout, 'state');
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.GridLayout, 'state');
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.GridLayout, 'state');
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.GridLayout, 'state');
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.GridLayout, 'state');
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.GridLayout, 'state');
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.GridLayout, 'state');
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.GridLayout, 'state');
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.GridLayout, 'state');
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.GridLayout, 'state');
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.GridLayout, 'state');
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.GridLayout, 'state');
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.GridLayout, 'state');
            app.Button_40.Text = '';
            app.Button_41 = uibutton(app.GridLayout, 'state');
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.GridLayout, 'state');
            app.Button_42.Text = '';
            app.Button_43 = uibutton(app.GridLayout, 'state');
            app.Button_43.Text = '';
            app.Button_44 = uibutton(app.GridLayout, 'state');
            app.Button_44.Text = '';
            app.Button_45 = uibutton(app.GridLayout, 'state');
            app.Button_45.Text = '';
            app.Button_46 = uibutton(app.GridLayout, 'state');
            app.Button_46.Text = '';
            app.Button_47 = uibutton(app.GridLayout, 'state');
            app.Button_47.Text = '';
            app.Button_48 = uibutton(app.GridLayout, 'state');
            app.Button_48.Text = '';
            app.Button_49 = uibutton(app.GridLayout, 'state');
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.GridLayout, 'state');
            app.Button_50.Text = '';
            app.Button_51 = uibutton(app.GridLayout, 'state');
            app.Button_51.Text = '';
            app.Button_52 = uibutton(app.GridLayout, 'state');
            app.Button_52.Text = '';
            app.Button_53 = uibutton(app.GridLayout, 'state');
            app.Button_53.Text = '';
            app.Button_54 = uibutton(app.GridLayout, 'state');
            app.Button_54.Text = '';
            app.Button_55 = uibutton(app.GridLayout, 'state');
            app.Button_55.Text = '';
            app.Button_56 = uibutton(app.GridLayout, 'state');
            app.Button_56.Text = '';
            app.Button_57 = uibutton(app.GridLayout, 'state');
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.GridLayout, 'state');
            app.Button_58.Text = '';
            app.Button_59 = uibutton(app.GridLayout, 'state');
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.GridLayout, 'state');
            app.Button_60.Text = '';
            app.Button_61 = uibutton(app.GridLayout, 'state');
            app.Button_61.Text = '';
            app.Button_62 = uibutton(app.GridLayout, 'state');
            app.Button_62.Text = '';
            app.Button_63 = uibutton(app.GridLayout, 'state');
            app.Button_63.Text = '';
            app.Button_64 = uibutton(app.GridLayout, 'state');
            app.Button_64.Text = '';
            app.Button_65 = uibutton(app.GridLayout, 'state');
            app.Button_65.Text = '';
            app.Button_66 = uibutton(app.GridLayout, 'state');
            app.Button_66.Text = '';
            app.Button_67 = uibutton(app.GridLayout, 'state');
            app.Button_67.Text = '';
            app.Button_68 = uibutton(app.GridLayout, 'state');
            app.Button_68.Text = '';
            app.Button_69 = uibutton(app.GridLayout, 'state');
            app.Button_69.Text = '';
            app.Button_70 = uibutton(app.GridLayout, 'state');
            app.Button_70.Text = '';
            app.Button_71 = uibutton(app.GridLayout, 'state');
            app.Button_71.Text = '';
            app.Button_72 = uibutton(app.GridLayout, 'state');
            app.Button_72.Text = '';
            app.Button_73 = uibutton(app.GridLayout, 'state');
            app.Button_73.Text = '';
            app.Button_74 = uibutton(app.GridLayout, 'state');
            app.Button_74.Text = '';
            app.Button_75 = uibutton(app.GridLayout, 'state');
            app.Button_75.Text = '';
            app.Button_76 = uibutton(app.GridLayout, 'state');
            app.Button_76.Text = '';
            app.Button_77 = uibutton(app.GridLayout, 'state');
            app.Button_77.Text = '';
            app.Button_78 = uibutton(app.GridLayout, 'state');
            app.Button_78.Text = '';
            app.Button_79 = uibutton(app.GridLayout, 'state');
            app.Button_79.Text = '';
            app.Button_80 = uibutton(app.GridLayout, 'state');
            app.Button_80.Text = '';
            app.Button_81 = uibutton(app.GridLayout, 'state');
            app.Button_81.Text = '';
            app.Button_82 = uibutton(app.GridLayout, 'state');
            app.Button_82.Text = '';
            app.Button_83 = uibutton(app.GridLayout, 'state');
            app.Button_83.Text = '';
            app.Button_84 = uibutton(app.GridLayout, 'state');
            app.Button_84.Text = '';
            app.Button_85 = uibutton(app.GridLayout, 'state');
            app.Button_85.Text = '';
            app.Button_86 = uibutton(app.GridLayout, 'state');
            app.Button_86.Text = '';
            app.Button_87 = uibutton(app.GridLayout, 'state');
            app.Button_87.Text = '';
            app.Button_88 = uibutton(app.GridLayout, 'state');
            app.Button_88.Text = '';
            app.Button_89 = uibutton(app.GridLayout, 'state');
            app.Button_89.Text = '';
            app.Button_90 = uibutton(app.GridLayout, 'state');
            app.Button_90.Text = '';
            app.Button_91 = uibutton(app.GridLayout, 'state');
            app.Button_91.Text = '';
            app.Button_92 = uibutton(app.GridLayout, 'state');
            app.Button_92.Text = '';
            app.Button_93 = uibutton(app.GridLayout, 'state');
            app.Button_93.Text = '';
            app.Button_94 = uibutton(app.GridLayout, 'state');
            app.Button_94.Text = '';
            app.Button_95 = uibutton(app.GridLayout, 'state');
            app.Button_95.Text = '';
            app.Button_96 = uibutton(app.GridLayout, 'state');
            app.Button_96.Text = '';
            app.Button_97 = uibutton(app.GridLayout, 'state');
            app.Button_97.Text = '';
            app.Button_98 = uibutton(app.GridLayout, 'state');
            app.Button_98.Text = '';
            app.Button_99 = uibutton(app.GridLayout, 'state');
            app.Button_99.Text = '';
            app.Button_100 = uibutton(app.GridLayout, 'state');
            app.Button_100.Text = '';
            app.Button_101 = uibutton(app.GridLayout, 'state');
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.GridLayout, 'state');
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.GridLayout, 'state');
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.GridLayout, 'state');
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.GridLayout, 'state');
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.GridLayout, 'state');
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.GridLayout, 'state');
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.GridLayout, 'state');
            app.Button_108.Text = '';
            app.Button_109 = uibutton(app.GridLayout, 'state');
            app.Button_109.Text = '';
            app.Button_110 = uibutton(app.GridLayout, 'state');
            app.Button_110.Text = '';
            app.Button_111 = uibutton(app.GridLayout, 'state');
            app.Button_111.Text = '';
            app.Button_112 = uibutton(app.GridLayout, 'state');
            app.Button_112.Text = '';
            app.Button_113 = uibutton(app.GridLayout, 'state');
            app.Button_113.Text = '';
            app.Button_114 = uibutton(app.GridLayout, 'state');
            app.Button_114.Text = '';
            app.Button_115 = uibutton(app.GridLayout, 'state');
            app.Button_115.Text = '';
            app.Button_116 = uibutton(app.GridLayout, 'state');
            app.Button_116.Text = '';
            app.Button_117 = uibutton(app.GridLayout, 'state');
            app.Button_117.Text = '';
            app.Button_118 = uibutton(app.GridLayout, 'state');
            app.Button_118.Enable = 'off';
            app.Button_118.Text = '';
            app.Button_119 = uibutton(app.GridLayout, 'state');
            app.Button_119.Enable = 'off';
            app.Button_119.Text = '';
            app.Button_120 = uibutton(app.GridLayout, 'state');
            app.Button_120.Text = '';
            app.Button_121 = uibutton(app.GridLayout, 'state');
            app.Button_121.Text = '';
            app.Button_122 = uibutton(app.GridLayout, 'state');
            app.Button_122.Text = '';
            app.Button_123 = uibutton(app.GridLayout, 'state');
            app.Button_123.Text = '';
            app.Button_124 = uibutton(app.GridLayout, 'state');
            app.Button_124.Text = '';
            app.Button_125 = uibutton(app.GridLayout, 'state');
            app.Button_125.Text = '';
            app.Button_126 = uibutton(app.GridLayout, 'state');
            app.Button_126.Text = '';
            app.PrintPanel = uipanel(app.RobotArmGridUIFigure);
            app.PrintPanel.Title = 'Print';
            app.GridLayout_2 = uigridlayout(app.PrintPanel);
            app.Button_127 = uibutton(app.GridLayout_2, 'state');
            app.Button_127.Enable = 'off';
            app.Button_127.Text = '';
            app.Button_128 = uibutton(app.GridLayout_2, 'state');
            app.Button_128.Enable = 'off';
            app.Button_128.Text = '';
            app.Button_129 = uibutton(app.GridLayout_2, 'state');
            app.Button_129.Enable = 'off';
            app.Button_129.Text = '';
            app.Button_130 = uibutton(app.GridLayout_2, 'state');
            app.Button_130.Enable = 'off';
            app.Button_130.Text = '';
            app.Button_131 = uibutton(app.GridLayout_2, 'state');
            app.Button_131.Enable = 'off';
            app.Button_131.Text = '';
            app.Button_132 = uibutton(app.GridLayout_2, 'state');
            app.Button_132.Enable = 'off';
            app.Button_132.Text = '';
            app.Button_133 = uibutton(app.GridLayout_2, 'state');
            app.Button_133.Text = '';
            app.Button_134 = uibutton(app.GridLayout_2, 'state');
            app.Button_134.Text = '';
            app.Button_135 = uibutton(app.GridLayout_2, 'state');
            app.Button_135.Text = '';
            app.Button_136 = uibutton(app.GridLayout_2, 'state');
            app.Button_136.Enable = 'off';
            app.Button_136.Text = '';
            app.Button_137 = uibutton(app.GridLayout_2, 'state');
            app.Button_137.Enable = 'off';
            app.Button_137.Text = '';
            app.Button_138 = uibutton(app.GridLayout_2, 'state');
            app.Button_138.Enable = 'off';
            app.Button_138.Text = '';
            app.Button_139 = uibutton(app.GridLayout_2, 'state');
            app.Button_139.Enable = 'off';
            app.Button_139.Text = '';
            app.Button_140 = uibutton(app.GridLayout_2, 'state');
            app.Button_140.Text = '';
            app.Button_141 = uibutton(app.GridLayout_2, 'state');
            app.Button_141.Text = '';
            app.Button_142 = uibutton(app.GridLayout_2, 'state');
            app.Button_142.Text = '';
            app.Button_143 = uibutton(app.GridLayout_2, 'state');
            app.Button_143.Text = '';
            app.Button_144 = uibutton(app.GridLayout_2, 'state');
            app.Button_144.Text = '';
            app.Button_145 = uibutton(app.GridLayout_2, 'state');
            app.Button_145.Enable = 'off';
            app.Button_145.Text = '';
            app.Button_146 = uibutton(app.GridLayout_2, 'state');
            app.Button_146.Enable = 'off';
            app.Button_146.Text = '';
            app.Button_147 = uibutton(app.GridLayout_2, 'state');
            app.Button_147.Text = '';
            app.Button_148 = uibutton(app.GridLayout_2, 'state');
            app.Button_148.Text = '';
            app.Button_149 = uibutton(app.GridLayout_2, 'state');
            app.Button_149.Text = '';
            app.Button_150 = uibutton(app.GridLayout_2, 'state');
            app.Button_150.Text = '';
            app.Button_151 = uibutton(app.GridLayout_2, 'state');
            app.Button_151.Text = '';
            app.Button_152 = uibutton(app.GridLayout_2, 'state');
            app.Button_152.Text = '';
            app.Button_153 = uibutton(app.GridLayout_2, 'state');
            app.Button_153.Text = '';
            app.Button_154 = uibutton(app.GridLayout_2, 'state');
            app.Button_154.Text = '';
            app.Button_155 = uibutton(app.GridLayout_2, 'state');
            app.Button_155.Text = '';
            app.Button_156 = uibutton(app.GridLayout_2, 'state');
            app.Button_156.Text = '';
            app.Button_157 = uibutton(app.GridLayout_2, 'state');
            app.Button_157.Text = '';
            app.Button_158 = uibutton(app.GridLayout_2, 'state');
            app.Button_158.Text = '';
            app.Button_159 = uibutton(app.GridLayout_2, 'state');
            app.Button_159.Text = '';
            app.Button_160 = uibutton(app.GridLayout_2, 'state');
            app.Button_160.Text = '';
            app.Button_161 = uibutton(app.GridLayout_2, 'state');
            app.Button_161.Text = '';
            app.Button_162 = uibutton(app.GridLayout_2, 'state');
            app.Button_162.Text = '';
            app.Button_163 = uibutton(app.GridLayout_2, 'state');
            app.Button_163.Text = '';
            app.Button_164 = uibutton(app.GridLayout_2, 'state');
            app.Button_164.Text = '';
            app.Button_165 = uibutton(app.GridLayout_2, 'state');
            app.Button_165.Text = '';
            app.Button_166 = uibutton(app.GridLayout_2, 'state');
            app.Button_166.Text = '';
            app.Button_167 = uibutton(app.GridLayout_2, 'state');
            app.Button_167.Text = '';
            app.Button_168 = uibutton(app.GridLayout_2, 'state');
            app.Button_168.Text = '';
            app.Button_169 = uibutton(app.GridLayout_2, 'state');
            app.Button_169.Text = '';
            app.Button_170 = uibutton(app.GridLayout_2, 'state');
            app.Button_170.Text = '';
            app.Button_171 = uibutton(app.GridLayout_2, 'state');
            app.Button_171.Text = '';
            app.Button_172 = uibutton(app.GridLayout_2, 'state');
            app.Button_172.Text = '';
            app.Button_173 = uibutton(app.GridLayout_2, 'state');
            app.Button_173.Text = '';
            app.Button_174 = uibutton(app.GridLayout_2, 'state');
            app.Button_174.Text = '';
            app.Button_175 = uibutton(app.GridLayout_2, 'state');
            app.Button_175.Text = '';
            app.Button_176 = uibutton(app.GridLayout_2, 'state');
            app.Button_176.Text = '';
            app.Button_177 = uibutton(app.GridLayout_2, 'state');
            app.Button_177.Text = '';
            app.Button_178 = uibutton(app.GridLayout_2, 'state');
            app.Button_178.Text = '';
            app.Button_179 = uibutton(app.GridLayout_2, 'state');
            app.Button_179.Text = '';
            app.Button_180 = uibutton(app.GridLayout_2, 'state');
            app.Button_180.Text = '';
            app.Button_181 = uibutton(app.GridLayout_2, 'state');
            app.Button_181.Text = '';
            app.Button_182 = uibutton(app.GridLayout_2, 'state');
            app.Button_182.Text = '';
            app.Button_183 = uibutton(app.GridLayout_2, 'state');
            app.Button_183.Text = '';
            app.Button_184 = uibutton(app.GridLayout_2, 'state');
            app.Button_184.Text = '';
            app.Button_185 = uibutton(app.GridLayout_2, 'state');
            app.Button_185.Text = '';
            app.Button_186 = uibutton(app.GridLayout_2, 'state');
            app.Button_186.Text = '';
            app.Button_187 = uibutton(app.GridLayout_2, 'state');
            app.Button_187.Text = '';
            app.Button_188 = uibutton(app.GridLayout_2, 'state');
            app.Button_188.Text = '';
            app.Button_189 = uibutton(app.GridLayout_2, 'state');
            app.Button_189.Text = '';
            app.Button_190 = uibutton(app.GridLayout_2, 'state');
            app.Button_190.Text = '';
            app.Button_191 = uibutton(app.GridLayout_2, 'state');
            app.Button_191.Text = '';
            app.Button_192 = uibutton(app.GridLayout_2, 'state');
            app.Button_192.Text = '';
            app.Button_193 = uibutton(app.GridLayout_2, 'state');
            app.Button_193.Text = '';
            app.Button_194 = uibutton(app.GridLayout_2, 'state');
            app.Button_194.Text = '';
            app.Button_195 = uibutton(app.GridLayout_2, 'state');
            app.Button_195.Text = '';
            app.Button_196 = uibutton(app.GridLayout_2, 'state');
            app.Button_196.Text = '';
            app.Button_197 = uibutton(app.GridLayout_2, 'state');
            app.Button_197.Text = '';
            app.Button_198 = uibutton(app.GridLayout_2, 'state');
            app.Button_198.Text = '';
            app.Button_199 = uibutton(app.GridLayout_2, 'state');
            app.Button_199.Text = '';
            app.Button_200 = uibutton(app.GridLayout_2, 'state');
            app.Button_200.Text = '';
            app.Button_201 = uibutton(app.GridLayout_2, 'state');
            app.Button_201.Text = '';
            app.Button_202 = uibutton(app.GridLayout_2, 'state');
            app.Button_202.Text = '';
            app.Button_203 = uibutton(app.GridLayout_2, 'state');
            app.Button_203.Text = '';
            app.Button_204 = uibutton(app.GridLayout_2, 'state');
            app.Button_204.Text = '';
            app.Button_205 = uibutton(app.GridLayout_2, 'state');
            app.Button_205.Text = '';
            app.Button_206 = uibutton(app.GridLayout_2, 'state');
            app.Button_206.Text = '';
            app.Button_207 = uibutton(app.GridLayout_2, 'state');
            app.Button_207.Text = '';
            app.Button_208 = uibutton(app.GridLayout_2, 'state');
            app.Button_208.Text = '';
            app.Button_209 = uibutton(app.GridLayout_2, 'state');
            app.Button_209.Text = '';
            app.Button_210 = uibutton(app.GridLayout_2, 'state');
            app.Button_210.Text = '';
            app.Button_211 = uibutton(app.GridLayout_2, 'state');
            app.Button_211.Text = '';
            app.Button_212 = uibutton(app.GridLayout_2, 'state');
            app.Button_212.Text = '';
            app.Button_213 = uibutton(app.GridLayout_2, 'state');
            app.Button_213.Text = '';
            app.Button_214 = uibutton(app.GridLayout_2, 'state');
            app.Button_214.Text = '';
            app.Button_215 = uibutton(app.GridLayout_2, 'state');
            app.Button_215.Text = '';
            app.Button_216 = uibutton(app.GridLayout_2, 'state');
            app.Button_216.Text = '';
            app.Button_217 = uibutton(app.GridLayout_2, 'state');
            app.Button_217.Text = '';
            app.Button_218 = uibutton(app.GridLayout_2, 'state');
            app.Button_218.Text = '';
            app.Button_219 = uibutton(app.GridLayout_2, 'state');
            app.Button_219.Text = '';
            app.Button_220 = uibutton(app.GridLayout_2, 'state');
            app.Button_220.Text = '';
            app.Button_221 = uibutton(app.GridLayout_2, 'state');
            app.Button_221.Text = '';
            app.Button_222 = uibutton(app.GridLayout_2, 'state');
            app.Button_222.Text = '';
            app.Button_223 = uibutton(app.GridLayout_2, 'state');
            app.Button_223.Text = '';
            app.Button_224 = uibutton(app.GridLayout_2, 'state');
            app.Button_224.Text = '';
            app.Button_225 = uibutton(app.GridLayout_2, 'state');
            app.Button_225.Text = '';
            app.Button_226 = uibutton(app.GridLayout_2, 'state');
            app.Button_226.Text = '';
            app.Button_227 = uibutton(app.GridLayout_2, 'state');
            app.Button_227.Text = '';
            app.Button_228 = uibutton(app.GridLayout_2, 'state');
            app.Button_228.Text = '';
            app.Button_229 = uibutton(app.GridLayout_2, 'state');
            app.Button_229.Text = '';
            app.Button_230 = uibutton(app.GridLayout_2, 'state');
            app.Button_230.Text = '';
            app.Button_231 = uibutton(app.GridLayout_2, 'state');
            app.Button_231.Text = '';
            app.Button_232 = uibutton(app.GridLayout_2, 'state');
            app.Button_232.Text = '';
            app.Button_233 = uibutton(app.GridLayout_2, 'state');
            app.Button_233.Text = '';
            app.Button_234 = uibutton(app.GridLayout_2, 'state');
            app.Button_234.Text = '';
            app.Button_235 = uibutton(app.GridLayout_2, 'state');
            app.Button_235.Text = '';
            app.Button_236 = uibutton(app.GridLayout_2, 'state');
            app.Button_236.Text = '';
            app.Button_237 = uibutton(app.GridLayout_2, 'state');
            app.Button_237.Text = '';
            app.Button_238 = uibutton(app.GridLayout_2, 'state');
            app.Button_238.Text = '';
            app.Button_239 = uibutton(app.GridLayout_2, 'state');
            app.Button_239.Text = '';
            app.Button_240 = uibutton(app.GridLayout_2, 'state');
            app.Button_240.Text = '';
            app.Button_241 = uibutton(app.GridLayout_2, 'state');
            app.Button_241.Text = '';
            app.Button_242 = uibutton(app.GridLayout_2, 'state');
            app.Button_242.Text = '';
            app.Button_243 = uibutton(app.GridLayout_2, 'state');
            app.Button_243.Text = '';
            app.Button_244 = uibutton(app.GridLayout_2, 'state');
            app.Button_244.Text = '';
            app.Button_245 = uibutton(app.GridLayout_2, 'state');
            app.Button_245.Text = '';
            app.Button_246 = uibutton(app.GridLayout_2, 'state');
            app.Button_246.Text = '';
            app.Button_247 = uibutton(app.GridLayout_2, 'state');
            app.Button_247.Text = '';
            app.Button_248 = uibutton(app.GridLayout_2, 'state');
            app.Button_248.Text = '';
            app.Button_249 = uibutton(app.GridLayout_2, 'state');
            app.Button_249.Text = '';
            app.Button_250 = uibutton(app.GridLayout_2, 'state');
            app.Button_250.Text = '';
            app.Button_251 = uibutton(app.GridLayout_2, 'state');
            app.Button_251.Enable = 'off';
            app.Button_251.Text = '';
            app.Button_252 = uibutton(app.GridLayout_2, 'state');
            app.Button_252.Enable = 'off';
            app.Button_252.Text = '';
            app.ConfirmButton = uibutton(app.RobotArmGridUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.RobotArmGridUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotArmGridUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotArmGridUIFigure)
        end
    end
end